classdef highlightSegments < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveButton               matlab.ui.control.Button
        SavetremorindexCheckBox  matlab.ui.control.CheckBox
        NextButton               matlab.ui.control.Button
        PreviousButton           matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data2plot
        data2export
        condit
        segIdx = 1
    end
    
    methods (Access = private)
        
        
        function plotNextSegment(app)
            cla(app.UIAxes); cla(app.UIAxes2); cla(app.UIAxes3);% clear existing plot
            epochTimes = app.data2plot.epochIdxs{app.condit,1};
            timeScale = epochTimes(app.segIdx,2) - epochTimes(app.segIdx,1);

            % Plot expanded data points and highlighted tremor segment
            plot(app.UIAxes, app.data2plot.bpVec(epochTimes(app.segIdx,1):epochTimes(app.segIdx,2)),'Color','r')
            plot(app.UIAxes2, app.data2plot.sig{1}(epochTimes(app.segIdx,1):epochTimes(app.segIdx,2)),'Color','k')
            plot(app.UIAxes3,app.data2plot.sigUpsamp(epochTimes(app.segIdx,1):epochTimes(app.segIdx,2)),'Color','b');
            xlim([app.UIAxes app.UIAxes2 app.UIAxes3],[1 timeScale])
            linkaxes([app.UIAxes app.UIAxes2 app.UIAxes3],'x')
            %legend(app.UIAxes, 'show');
        end
        
        function plotPriorSegment(app)
            cla(app.UIAxes); cla(app.UIAxes2); cla(app.UIAxes3);% clear existing plot
            epochTimes = app.data2plot.epochIdxs{app.condit,1};
            timeScale = epochTimes(app.segIdx,2) - epochTimes(app.segIdx,1);

            % Plot expanded data points and highlighted tremor segment
            plot(app.UIAxes, app.data2plot.bpVec(epochTimes(app.segIdx,1):epochTimes(app.segIdx,2)),'Color','r')
            plot(app.UIAxes2, app.data2plot.sig{1}(epochTimes(app.segIdx,1):epochTimes(app.segIdx,2)),'Color','k')
            plot(app.UIAxes3, app.data2plot.sigUpsamp(epochTimes(app.segIdx,1):epochTimes(app.segIdx,2)),'Color','b');
            xlim([app.UIAxes app.UIAxes2 app.UIAxes3],[1 timeScale])
            linkaxes([app.UIAxes app.UIAxes2 app.UIAxes3],'x')
            %legend(app.UIAxes, 'show');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app, condit, sigUpsamp, tremorIdxs)
            app.data2export.goodTremIdxs = tremorIdxs;
            app.condit = condit;
            app.data2plot = load(uigetfile("*.mat"),'sig','tremEpochs','epochIdxs');
            app.data2plot.sigUpsamp = sigUpsamp;
            app.data2plot.bpVec = bandpass(app.data2plot.sigUpsamp,[10 30],30000);
            epochTimes = app.data2plot.epochIdxs{app.condit,1};
            plot(app.UIAxes, app.data2plot.bpVec(epochTimes(1,1):epochTimes(1,2)),'Color','r')
            plot(app.UIAxes2, app.data2plot.sig{1}(epochTimes(1,1):epochTimes(1,2)),'Color','k')
            plot(app.UIAxes3, app.data2plot.sigUpsamp(epochTimes(1,1):epochTimes(1,2)),'Color','b');
            linkaxes([app.UIAxes app.UIAxes2 app.UIAxes3],'x')
        end

        % Button pushed function: NextButton
        function nextSegment(app, event)

            if app.segIdx < length(app.data2plot.tremEpochs{app.condit,1})
                app.segIdx = app.segIdx + 1;
                app.SavetremorindexCheckBox.Value = 0;
                plotNextSegment(app);
            else
                uialert(app.UIFigure, 'End of data reached!', 'Info', 'Icon', 'info');
            end

        end

        % Value changed function: SavetremorindexCheckBox
        function saveTremorIndex(app, event)
            value = app.SavetremorindexCheckBox.Value;

            if value == 1
                app.data2export.goodTremIdxs(app.condit,app.segIdx) = 1;
            else
                app.data2export.goodTremIdxs(app.condit,app.segIdx) = 0;
            end

            
        end

        % Button pushed function: PreviousButton
        function previousButtonPushed(app, event)
            if app.segIdx > 1    
                app.segIdx = app.segIdx - 1;
                app.SavetremorindexCheckBox.Value = 0;
%                 if app.data2export.goodTremIdxs(app.segIdx) == 1
%                     app.SavetremorindexCheckBox.Value = 1;
%                 else
%                     app.SavetremorindexCheckBox.Value = 0;
%                 end

                plotPriorSegment(app);

            else
                uialert(app.UIFigure, 'End of data reached!', 'Info', 'Icon', 'info');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin('base','tremIdxs',app.data2export)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tremor segment')
            xlabel(app.UIAxes, 'Timepts')
            ylabel(app.UIAxes, '\muVs')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Ephys segment')
            xlabel(app.UIAxes2, 'Timepts')
            ylabel(app.UIAxes2, '\muVs')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Raw movement')
            xlabel(app.UIAxes3, 'Timepts')
            zlabel(app.UIAxes3, 'Z')
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @nextSegment, true);
            app.NextButton.Text = 'Next';
            app.SavetremorindexCheckBox = uicheckbox(app.UIFigure);
            app.SavetremorindexCheckBox.ValueChangedFcn = createCallbackFcn(app, @saveTremorIndex, true);
            app.SavetremorindexCheckBox.Text = 'Save tremor index';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = highlightSegments(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end